classdef ComparisonGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Tree_2                      matlab.ui.container.Tree
        Button_2                    matlab.ui.control.Button
        OutputDirectoryEditField_2  matlab.ui.control.EditField
        Button_1                    matlab.ui.control.Button
        OutputDirectoryEditField    matlab.ui.control.EditField
        Button                      matlab.ui.control.Button
        SelectBestButton            matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        COMPARISONLabel             matlab.ui.control.Label
        Tree                        matlab.ui.container.Tree
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Mainapp
        Handles
        Values
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, handles, parameters)
            %app.Handles = handles;
            app.Mainapp = mainapp;

            app.Values.image1 = [];
            app.Values.image2 = [];

            app.Values.label1 = ' ';
            app.Values.label2 = ' ';
        
            app.Values.path0 = which('SENPAI_mixedVer');
            app.Values.path0 = app.Values.path0(1:39);
        end

        % Button pushed function: Button_1
        function ChooseDirectory1(app, event)
            path = uigetdir('title','Select result Directory');
            if isempty(path)
                msgbox('No directory chosen');
                return;
            else

                set(app.OutputDirectoryEditField, 'Value', path)

                dir_path = dir(path);
                dir_path = dir_path(~ismember({dir_path.name}, {'.', '..'}));

                for i = 1:size(dir_path,1)
                    folder = dir_path(i).name;
                    folder_path = dir_path(i).folder;
                    dir_log = dir([folder_path '\' folder '\Segmentations\']);
                    dir_log = dir_log(~ismember({dir_log.name}, {'.', '..'}));
                    a = uitreenode("Parent",app.Tree, "Text", folder, 'Icon', [app.Values.path0 'app_functions\icons\folder.jpg'], 'NodeData', folder_path);
                    for j = 1:size(dir_log,1)
                        c = uitreenode('Parent', a, 'Text', dir_log(j).name, 'Icon', [app.Values.path0 'app_functions\icons\folder.jpg'], 'NodeData', dir_log(j).folder);
                        file_dir = dir([dir_log(j).folder '\' dir_log(j).name]);
                        file_dir = file_dir(~ismember({file_dir.name}, {'.', '..'}));
                        for t = 1:size(file_dir,1)
                            uitreenode('Parent', c, 'Text', file_dir(t).name, 'Icon', [app.Values.path0 'app_functions\icons\image_icon-icons.com_50366.png'], 'NodeData', file_dir(t).folder);
                        end
                    end
                    uitreenode('Parent', a, 'Text', 'Original Image', 'Icon', [app.Values.path0 'app_functions\icons\image_icon-icons.com_50366.png'], 'NodeData', 'Original');
                end
                set(app.Slider, 'Value', 1)
                set(app.Slider, 'Enable', 'Off')
            end
        end

        % Button pushed function: Button_2
        function ChooseDirectory2(app, event)
            path = uigetdir('title','Select result Directory');
            if isempty(path)
                msgbox('No directory chosen');
                return;
            else

                set(app.OutputDirectoryEditField_2, 'Value', path)

                dir_path = dir(path);
                dir_path = dir_path(~ismember({dir_path.name}, {'.', '..'}));

                for i = 1:size(dir_path,1)
                    folder = dir_path(i).name;
                    folder_path = dir_path(i).folder;
                    dir_log = dir([folder_path '\' folder '\Segmentations\']);
                    dir_log = dir_log(~ismember({dir_log.name}, {'.', '..'}));
                    a = uitreenode("Parent",app.Tree_2, "Text", folder, 'Icon', [app.Values.path0 'app_functions\icons\folder.jpg'], 'NodeData', folder_path);
                    for j = 1:size(dir_log,1)
                        c = uitreenode('Parent', a, 'Text', dir_log(j).name, 'Icon', [app.Values.path0 'app_functions\icons\folder.jpg'], 'NodeData', dir_log(j).folder);
                        file_dir = dir([dir_log(j).folder '\' dir_log(j).name]);
                        file_dir = file_dir(~ismember({file_dir.name}, {'.', '..'}));
                        for t = 1:size(file_dir,1)
                            uitreenode('Parent', c, 'Text', file_dir(t).name, 'Icon', [app.Values.path0 'app_functions\icons\image_icon-icons.com_50366.png'], 'NodeData', file_dir(t).folder);
                        end
                    end
                    uitreenode('Parent', a, 'Text', 'Original Image', 'Icon', [app.Values.path0 'app_functions\icons\image_icon-icons.com_50366.png'], 'NodeData', 'Original');
                end
                set(app.Slider, 'Value', 1)
                set(app.Slider, 'Enable', 'Off')
            end
        end

        % Double-clicked callback: Tree
        function ChooseImage1(app, event)
            app.Values.node1 = event.InteractionInformation.Node;

            if strcmp(app.Values.node1.NodeData, 'Original')
                [filename, filepath] = uigetfile('.tif');
                if ~isempty(filepath) && ~isempty(filename)
                    app.Values.image1 = SmRG_importData(filename, filepath);
                    app.Values.label1 = 'Original';
                    set(app.Slider, 'Enable', 'On')
                    set(app.Slider, 'Limits', [1 size(app.Values.image1, 3)])
                    if not(isempty(app.Values.image2))
                        imshowpair(app.Values.image1(:, :, floor(app.Slider.Value)), app.Values.image2(:, :, floor(app.Slider.Value)), 'Parent', app.UIAxes); %'XData',[1 app.handles.righe],'YData',[1 app.handles.colonne]);
                    else
                        app.Values.image2 = zeros(size(app.Values.image1));
                        imshowpair(app.Values.image1(:, :, floor(app.Slider.Value)), app.Values.image2(:, :, floor(app.Slider.Value)), 'Parent', app.UIAxes);
                    end
                    ttl = sprintf( ['\\fontsize{10}','{\\color{green}%s} ' app.Values.label1, ' {\\color{white}%s}'  ' Intersection ','{\\color{magenta}%s} ' app.Values.label2],char(9632), char(9632), char(9632));
                    title(app.UIAxes, ttl);
                    app.UIAxes.XLim = [1 size(app.Values.image1,2)]; %Setto limite asse x che carico nel primo grafico
                    app.UIAxes.YLim = [1 size(app.Values.image1,1)]; %Setto limite asse y che carico nel primo grafico
                else
                    msgbox('No image loaded');
                    return;
                end
            else
                if strcmp(app.Values.node1.Text, 'senpai_final.mat')
                    app.Values.label1 = 'Segmentation';
                    check = 'senpai_final';
                    load(fullfile(app.Values.node1.NodeData, app.Values.node1.Text), check);
                    app.Values.image1 = senpai_final;
                else
                    app.Values.label1 = 'Parcellation';
                    check = 'parcel_final';
                    load(fullfile(app.Values.node1.NodeData, app.Values.node1.Text), check);
                    app.Values.image1 = parcel_final;
                end
                clear senpai_final parcel_final
                set(app.Slider, 'Enable', 'On')
                set(app.Slider, 'Limits', [1 size(app.Values.image1, 3)])
                if not(isempty(app.Values.image2))
                    imshowpair(app.Values.image1(:, :, floor(app.Slider.Value)), app.Values.image2(:, :, floor(app.Slider.Value)), 'Parent', app.UIAxes); %'XData',[1 app.handles.righe],'YData',[1 app.handles.colonne]);
                else
                    app.Values.image2 = zeros(size(app.Values.image1));
                    imshowpair(app.Values.image1(:, :, floor(app.Slider.Value)), app.Values.image2(:, :, floor(app.Slider.Value)), 'Parent', app.UIAxes);
                end
                ttl = sprintf( ['\\fontsize{10}','{\\color{green}%s} ' app.Values.label1, ' {\\color{white}%s}' ' Intersection ','{\\color{magenta}%s} ' app.Values.label2], char(9632), char(9632), char(9632));
                title(app.UIAxes, ttl);
                app.UIAxes.XLim = [1 size(app.Values.image1,2)]; %Setto limite asse x che carico nel primo grafico
                app.UIAxes.YLim = [1 size(app.Values.image1,1)]; %Setto limite asse y che carico nel primo grafico
            end
        end

        % Double-clicked callback: Tree_2
        function ChooseImage2(app, event)
            app.Values.node2 = event.InteractionInformation.Node;

            if strcmp(app.Values.node2.NodeData, 'Original')
                [filename, filepath] = uigetfile('.tif');
                if ~isempty(filepath) && ~isempty(filename)
                    app.Values.label2 = 'Original';
                    app.Values.image2 = SmRG_importData(filename, filepath);
                    set(app.Slider, 'Enable', 'On')
                    set(app.Slider, 'Limits', [1 size(app.Values.image2, 3)])
                    if not(isempty(app.Values.image1))
                        imshowpair(app.Values.image1(:, :, floor(app.Slider.Value)), app.Values.image2(:, :, floor(app.Slider.Value)), 'Parent', app.UIAxes); %'XData',[1 app.handles.righe],'YData',[1 app.handles.colonne]);
                        legend(app.UIAxes, app.Values.label1, app.Values.label2)
                    else
                        app.Values.image1 = zeros(size(app.Values.image1));
                        imshowpair(app.Values.image1(:, :, floor(app.Slider.Value)), app.Values.image2(:, :, floor(app.Slider.Value)), 'Parent', app.UIAxes);
                        legend(app.UIAxes, app.Values.label2)
                    end
                    ttl = sprintf( ['\\fontsize{10}','{\\color{green}%s} ' app.Values.label1, ' {\\color{white}%s}' ' Intersection ', '{\\color{magenta}%s} ' app.Values.label2],char(9632), char(9632), char(9632));
                    title(app.UIAxes, ttl);
                    app.UIAxes.XLim = [1 size(app.Values.image2,2)]; %Setto limite asse x che carico nel primo grafico
                    app.UIAxes.YLim = [1 size(app.Values.image2,1)]; %Setto limite asse y che carico nel primo grafico
                else
                    msgbox('No image loaded');
                    return;
                end
            else
                if strcmp(app.Values.node2.Text, 'senpai_final.mat')
                    app.Values.label2 = 'Segmentation';
                    check = 'senpai_final';
                    load(fullfile(app.Values.node2.NodeData, app.Values.node2.Text), check);
                    app.Values.image2 = senpai_final;
                else
                    app.Values.label2 = 'Parcellation';
                    check = 'parcel_final';
                    load(fullfile(app.Values.node2.NodeData, app.Values.node2.Text), check);
                    app.Values.image2 = parcel_final;
                end
                clear senpai_final parcel_final
                set(app.Slider, 'Enable', 'On')
                set(app.Slider, 'Limits', [1 size(app.Values.image2, 3)])
                if not(isempty(app.Values.image1))
                    imshowpair(app.Values.image1(:, :, floor(app.Slider.Value)), app.Values.image2(:, :, floor(app.Slider.Value)), 'Parent', app.UIAxes); %'XData',[1 app.handles.righe],'YData',[1 app.handles.colonne]);
                else
                    app.Values.image1 = zeros(size(app.Values.image2));
                    imshowpair(app.Values.image1(:, :, floor(app.Slider.Value)), app.Values.image2(:, :, floor(app.Slider.Value)), 'Parent', app.UIAxes);
                end
                ttl = sprintf( ['\\fontsize{10}','{\\color{green}%s} ' app.Values.label1, ' {\\color{white}%s}' ' Intersection ', '{\\color{magenta}%s} ' app.Values.label2],char(9632), char(9632), char(9632));
                title(app.UIAxes, ttl);
                app.UIAxes.XLim = [1 size(app.Values.image2,2)]; %Setto limite asse x che carico nel primo grafico
                app.UIAxes.YLim = [1 size(app.Values.image2,1)]; %Setto limite asse y che carico nel primo grafico
            end
        end

        % Value changed function: Slider
        function ChangedImage(app, event)
            app.Values.value = floor(app.Slider.Value);
            set(app.Label, 'Text', num2str(app.Values.value))
            imshowpair(app.Values.image1(:,:,app.Values.value),app.Values.image2(:, :, app.Values.value),'Parent',app.UIAxes);
        end

        % Value changing function: Slider
        function ChangingImage(app, event)
            app.Values.value = floor(event.Value);
            set(app.Label, 'Text', num2str(app.Values.value))
            imshowpair(app.Values.image1(:,:,app.Values.value),app.Values.image2(:, :, app.Values.value),'Parent',app.UIAxes);
        end

        % Key press function: UIFigure
        function KeyPress(app, event)
            key = event.Key;
            if ~isempty(app.Values.image1) || ~isempty(app.Values.image2)
                value=floor(app.Slider.Value); % Prendo il valore dello slider
                if strcmp(key,'leftarrow') %Se il sinistro
                    value=max(value-1, 1); %il valore dello slider lo diminuisco di uno
                elseif strcmp(key,'rightarrow') %se il destro
                    value = min(value+1, size(app.Values.image1, 3)); %il valore dello sliedr lo accresc odi uno
                end
                app.Slider.Value = value; %Aggiorno il valore dello slider
                ChangedImage(app, event); %Richiamo la funzione dello slider per aggiornare l'immagine
            end
        end

        % Button pushed function: SelectBestButton
        function SelectBest(app, event)
            question = {['\fontsize{12}Do you want to choose one of them as best?? ' ...
            'File will be saved in the result directory']};
            opt.Interpreter = 'tex';
            opt.Default = 'NO';
            answer = questdlg(question, 'Save image', 'Save green image', 'Save purple image', 'NO', opt);
            switch answer
                case 'Save green image'

                    % Cerco nome del file (il formato ".tif" può non essere
                    % in fondo)
                    path = reverse(app.Values.node1.NodeData);
                    path_save = reverse(extractAfter(path, '\fit.'));
                    path_save = [path_save '.tif'];
                    name = app.Values.node1.NodeData;
                    name = strsplit(name, '\');
                    name = name{end};
                    if ~exist(fullfile(path_save, 'Best'), 'dir')
                        mkdir(fullfile(path_save, 'Best'));
                    end
                    saveJ_2_tif(app.Values.image1, [name '.tif'], fullfile(path_save, 'Best'), 16)

                case 'Save purple image'

                    % Cerco nome del file (il formato ".tif" può non essere
                    % in fondo)
                    path = reverse(app.Values.node2.NodeData);
                    path_save = reverse(extractAfter(path, '\fit.'));
                    path_save = [path_save '.tif'];
                    name = app.Values.node2.NodeData;
                    name = strsplit(name, '\');
                    name = name{end};
                    if ~exist(fullfile(path_save, 'Best'), 'dir')
                        mkdir(fullfile(path_save, 'Best'));
                    end
                    saveJ_2_tif(app.Values.image2, [name '.tif'], fullfile(path_save, 'Best'), 16)

                otherwise
                    return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.Tree = uitree(app.UIFigure);
            app.Tree.DoubleClickedFcn = createCallbackFcn(app, @ChooseImage1, true);
            app.COMPARISONLabel = uilabel(app.UIFigure);
            app.COMPARISONLabel.Text = 'COMPARISON';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @ChangedImage, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @ChangingImage, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.SelectBestButton = uibutton(app.UIFigure, 'push');
            app.SelectBestButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBest, true);
            app.SelectBestButton.Text = 'Select Best';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'question';
            app.Button.Text = '';
            app.OutputDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @ChooseDirectory1, true);
            app.Button_1.Icon = 'folder.jpg';
            app.Button_1.Text = '';
            app.OutputDirectoryEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseDirectory2, true);
            app.Button_2.Icon = 'folder.jpg';
            app.Button_2.Text = '';
            app.Tree_2 = uitree(app.UIFigure);
            app.Tree_2.DoubleClickedFcn = createCallbackFcn(app, @ChooseImage2, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComparisonGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end